classdef Reto2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        EmpezarButton                  matlab.ui.control.Button
        CargaPlacasx106Label           matlab.ui.control.Label
        cargaPlacas                    matlab.ui.control.NumericEditField
        CargaParticulax106Label        matlab.ui.control.Label
        CargaParticula                 matlab.ui.control.NumericEditField
        DistanciaPlacasEditFieldLabel  matlab.ui.control.Label
        DistanciaPlacas                matlab.ui.control.NumericEditField
        PosicionParticulaxEditFieldLabel  matlab.ui.control.Label
        PosicionParticulax             matlab.ui.control.NumericEditField
        PosicionParticulayLabel        matlab.ui.control.Label
        PosicionParticulay             matlab.ui.control.NumericEditField
        MasadelaParticulax106Label     matlab.ui.control.Label
        MasaParticula                  matlab.ui.control.NumericEditField
        VelocidadXKnobLabel            matlab.ui.control.Label
        VelocidadX                     matlab.ui.control.Knob
        VelocidadYLabel                matlab.ui.control.Label
        VelocidadY                     matlab.ui.control.Knob
        PosicionParticulazLabel        matlab.ui.control.Label
        PosicionParticulaz             matlab.ui.control.NumericEditField
        TamaoPlaca1Label               matlab.ui.control.Label
        TamPlaca1                      matlab.ui.control.NumericEditField
        TamaoPlaca1Label_2             matlab.ui.control.Label
        TamPlaca2                      matlab.ui.control.NumericEditField
        VelocidadZLabel                matlab.ui.control.Label
        VelocidadZ                     matlab.ui.control.Knob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EmpezarButton
        function EmpezarButtonPushed(app, event)
%% RETO PARTE 2
cla(app.UIAxes)

er = 1;                 % Permitividad relativa del medio
e0 = 8.8541878176e-12;  % Permitividad eléctrica en el vacío
ke = 1/(4*pi*er*e0);    % Constante eléctrica

%% Datos de entrada
N = 100;

% Valores de las cargas
Q = app.cargaPlacas.Value*1e-6;
q(1:N/2) = -Q/N;
q(N/2+1:N) = +Q/N;

% Particula
carga_particula = app.CargaParticula.Value*1e-6;
mp = app.MasaParticula.Value*1e-6;
px0 = app.PosicionParticulax.Value;
py0 = app.PosicionParticulay.Value;
pz0 = app.PosicionParticulaz.Value;
vx0 = app.VelocidadX.Value;
vy0 = app.VelocidadY.Value;
vz0 = app.VelocidadZ.Value;

% Posiciones en x de las cargas
distanciaP = app.DistanciaPlacas.Value;
qx(1:N/2) = 0;
qx(N/2+1:N) = abs(distanciaP);
posPlaca1 = 0;
posPlaca2 = distanciaP;

% Posiciones en y de las cargas
tam_placa1 = app.TamPlaca1.Value;
tam_placa2 = app.TamPlaca2.Value;

if tam_placa1 > tam_placa2
    difTam = tam_placa1-tam_placa2;
    qy1 = linspace(0, tam_placa1, N/2);
    qy2 = linspace(difTam/2, difTam/2 + tam_placa2, N/2);
    qy = [qy1,qy2];
else
    difTam = tam_placa2-tam_placa1;
    qy1 = linspace(difTam/2, difTam/2 +tam_placa1, N/2);
    qy2 = linspace(0, tam_placa2, N/2);
    qy = [qy1,qy2];
end

% Posiciones en z de las cargas
placaz0 = -0.1;
placaz1 = 0.1;
qz(1:N/2) = placaz0;
qz(N/2+1:N) = placaz1;

%% Calculo campo electrico

% Valores de x, y en donde se va a evaluar la carga
n = 35;
x = linspace( min(qx), max(qx), n);
y = linspace( min(qy), max(qy), n);
z = linspace( placaz0, placaz1, n);
[sx, sy, sz] = meshgrid(x, y, z);

ex = zeros(size(sx));
ey = zeros(size(sy));
ez = zeros(size(sz));

for k = 1 : length(qy)
    [exi, eyi, ezi] = campoElectrico3(q(k), qx(k), qy(k), qz(k), sx, sy, sz, er);
    ex = ex + exi;
    ey = ey + eyi;
    ez = ez + ezi;   
end

em = sqrt(ex.^2+ey.^2+ez.^2);

% Grafica campo eléctrico
hold(app.UIAxes,'on')
quiver(app.UIAxes,sx, sy, ex./em, ey./em, 'color', 'green')
if tam_placa1>tam_placa2
    rectangle(app.UIAxes,'Position', [posPlaca1-0.01, -0.02, 0.02, tam_placa1 + 0.04], 'FaceColor','r')  
    rectangle(app.UIAxes,'Position', [posPlaca2, difTam/2-0.02, 0.02,  tam_placa2+0.04], 'FaceColor','b')
else
    rectangle(app.UIAxes,'Position', [posPlaca1-0.01, difTam/2-0.02 0.02, tam_placa1+0.04], 'FaceColor','r')  
    rectangle(app.UIAxes,'Position', [posPlaca2, -0.02, 0.02, tam_placa2+0.04], 'FaceColor','b')
end

xlabel(app.UIAxes,'X')
ylabel(app.UIAxes,'Y')
title(app.UIAxes,'Simulación')
axis(app.UIAxes,[ min(qx)-0.1 max(qx)+0.1 min(qy)-0.1 max(qy)+0.1  ])

%% Simulación
clear sx; clear sy; clear sz;

% Tiempo de la simulación
tmax = 0.1;
dt = 0.0001;

% Instantes de tiempo de la simulación
t = 0:dt:tmax;
nt = length(t);

%% Calculos Simulación

% x
sx0 = px0;
ax0 = 0;
vx(1) = vx0 + ax0.*dt;
sx(1) = sx0 + vx0.*dt;

% y
sy0 = py0;
ay0 = 0;
vy(1) = vy0 + ay0.*dt;
sy(1) = sy0 + vy0.*dt;

% z
sz0 = placaz0;
az0 = 0;
vz(1) = vz0 + az0.*dt;
sz(1) = sz0 + vz0.*dt;

if py0< max( tam_placa1, tam_placa2) && py0>0 && px0>0 && px0<distanciaP
    fx(1)  = carga_particula.*-ex(1);
    fy(1)  = carga_particula.*ey(1);
    fz(1)  = carga_particula.*ez(1);
else
    fx(1) = 0;
    fy(1) = 0.1;
    fz(1) = 0;
end
ax(1) = fx(1)./mp;
ay(1) = fy(1)./mp;
az(1) = fz(1)./mp;

scatter(app.UIAxes,px0, py0, 100, 'b', 'filled')
curve = animatedline(app.UIAxes,'Color','b','Marker', 'o','LineWidth',2);
set(gca,'XLim', [min(qx)-0.1 max(qx)+0.1], 'YLim', [min(qy)-0.1 max(qy)+0.1]);
legend(app.UIAxes,"Campo Electrico",'Particula','Location','southwest');

i = 2;
for k = 2:nt
    
    if k> (i*nt/n)
        i = i+1;
    end

    % y
    vy(k) = vy(k-1) + ay(k-1).*dt;
    sy(k) = sy(k-1) + vy(k-1).*dt;
    
    
    % x
    vx(k) = vx(k-1) + ax(k-1).*dt;
    sx(k) = sx(k-1) + vx(k-1).*dt;

    % z
    vz(k) = vz(k-1) + az(k-1).*dt;
    sz(k) = sz(k-1) + vz(k-1).*dt;
    
    if sy(k)< max( tam_placa1, tam_placa2) && sy(k) >0 && sx(k)>0 && sx(k)<distanciaP
        fx(k)  = carga_particula.*-ex(i);
        fy(k)  = carga_particula.*ey(i);
        fz(k) = carga_particula.*ez(i);
        fy(k) = 0;
    else
        fx(k) = 0;
        fy(k) = 0;
        fz(k) = 0;
    end
    ax(k) = fx(k)./mp;
    ay(k) = fy(k)./mp;
    az(k) = fz(k)./mp;
    
    if sx(k) > distanciaP+0.015 || sx(k) < 0
        break
    end
    
    if mod(k,5)==0
        addpoints(curve, sx(k), sy(k) )
        drawnow
    end
    
end
hold off
clc
clear
        end

        % Value changed function: cargaPlacas
        function cargaPlacasValueChanged(app, event)
            value = app.cargaPlacas.Value;
            
        end

        % Value changed function: CargaParticula
        function CargaParticulaValueChanged(app, event)
            value = app.CargaParticula.Value;
            
        end

        % Value changed function: VelocidadY
        function VelocidadYValueChanged(app, event)
            value = app.VelocidadY.Value;
            
        end

        % Callback function
        function VelocidadYValueChanging(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulacion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EmpezarButton = uibutton(app.UIFigure, 'push');
            app.EmpezarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.EmpezarButton.Text = 'Empezar';
            app.CargaPlacasx106Label = uilabel(app.UIFigure);
            app.CargaPlacasx106Label.Text = 'Carga Placas (x10-6)';
            app.cargaPlacas = uieditfield(app.UIFigure, 'numeric');
            app.cargaPlacas.Limits = [-100 100];
            app.cargaPlacas.ValueChangedFcn = createCallbackFcn(app, @cargaPlacasValueChanged, true);
            app.cargaPlacas.Value = -1;
            app.CargaParticulax106Label = uilabel(app.UIFigure);
            app.CargaParticulax106Label.Text = 'Carga Particula (x10-6)';
            app.CargaParticula = uieditfield(app.UIFigure, 'numeric');
            app.CargaParticula.Limits = [-100 100];
            app.CargaParticula.ValueChangedFcn = createCallbackFcn(app, @CargaParticulaValueChanged, true);
            app.CargaParticula.Value = -1;
            app.DistanciaPlacasEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaPlacasEditFieldLabel.Text = 'Distancia Placas';
            app.DistanciaPlacas = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaPlacas.Limits = [-10 10];
            app.DistanciaPlacas.Value = 0.4;
            app.PosicionParticulaxEditFieldLabel = uilabel(app.UIFigure);
            app.PosicionParticulaxEditFieldLabel.Text = 'Posicion Particula [x]';
            app.PosicionParticulax = uieditfield(app.UIFigure, 'numeric');
            app.PosicionParticulax.Limits = [0.1 Inf];
            app.PosicionParticulax.Value = 0.2;
            app.PosicionParticulayLabel = uilabel(app.UIFigure);
            app.PosicionParticulayLabel.Text = 'Posicion Particula [y]';
            app.PosicionParticulay = uieditfield(app.UIFigure, 'numeric');
            app.PosicionParticulay.Limits = [0 100];
            app.PosicionParticulay.Value = 1.1;
            app.MasadelaParticulax106Label = uilabel(app.UIFigure);
            app.MasadelaParticulax106Label.Text = 'Masa de la Particula (x10-6)';
            app.MasaParticula = uieditfield(app.UIFigure, 'numeric');
            app.MasaParticula.Limits = [0.1 100];
            app.MasaParticula.Value = 2;
            app.VelocidadXKnobLabel = uilabel(app.UIFigure);
            app.VelocidadXKnobLabel.Text = 'Velocidad X';
            app.VelocidadX = uiknob(app.UIFigure, 'continuous');
            app.VelocidadX.Limits = [-100 100];
            app.VelocidadX.Value = -10;
            app.VelocidadYLabel = uilabel(app.UIFigure);
            app.VelocidadYLabel.Text = 'Velocidad Y';
            app.VelocidadY = uiknob(app.UIFigure, 'continuous');
            app.VelocidadY.Limits = [-100 100];
            app.VelocidadY.ValueChangedFcn = createCallbackFcn(app, @VelocidadYValueChanged, true);
            app.VelocidadY.Value = -50;
            app.PosicionParticulazLabel = uilabel(app.UIFigure);
            app.PosicionParticulazLabel.Text = 'Posicion Particula [z]';
            app.PosicionParticulaz = uieditfield(app.UIFigure, 'numeric');
            app.PosicionParticulaz.Limits = [-10 10];
            app.TamaoPlaca1Label = uilabel(app.UIFigure);
            app.TamaoPlaca1Label.Text = 'Tamaño Placa 1';
            app.TamPlaca1 = uieditfield(app.UIFigure, 'numeric');
            app.TamPlaca1.Limits = [0.1 10];
            app.TamPlaca1.Value = 1;
            app.TamaoPlaca1Label_2 = uilabel(app.UIFigure);
            app.TamaoPlaca1Label_2.Text = 'Tamaño Placa 1';
            app.TamPlaca2 = uieditfield(app.UIFigure, 'numeric');
            app.TamPlaca2.Limits = [0.1 10];
            app.TamPlaca2.Value = 0.3;
            app.VelocidadZLabel = uilabel(app.UIFigure);
            app.VelocidadZLabel.Text = 'Velocidad Z';
            app.VelocidadZ = uiknob(app.UIFigure, 'continuous');
            app.VelocidadZ.Limits = [-100 100];
            app.VelocidadZ.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reto2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end